classdef TRPL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        NumberoffittrialstartpointsLabel  matlab.ui.control.Label
        numMultiStart                matlab.ui.control.NumericEditField
        ConvPadnsEditField           matlab.ui.control.NumericEditField
        ConvPadnsEditFieldLabel      matlab.ui.control.Label
        WindowBaselineEndindexEditField  matlab.ui.control.NumericEditField
        WindowBaselineEndindexEditFieldLabel  matlab.ui.control.Label
        WindowBaselineStartindexEditField  matlab.ui.control.NumericEditField
        WindowBaselineStartindexEditFieldLabel  matlab.ui.control.Label
        WindowUpperBoundnsEditField  matlab.ui.control.NumericEditField
        WindowUpperBoundnsEditFieldLabel  matlab.ui.control.Label
        WindowLowerBoundnsEditField  matlab.ui.control.NumericEditField
        WindowLowerBoundnsEditFieldLabel  matlab.ui.control.Label
        FitParametersTable           matlab.ui.control.Table
        BuildModelButton             matlab.ui.control.Button
        FitButton                    matlab.ui.control.Button
        ExportDataasmatButton        matlab.ui.control.Button
        ExportTRPLDecayasFigButton   matlab.ui.control.Button
        ExportResidualsasFigButton   matlab.ui.control.Button
        SetBaselineBoundsButton      matlab.ui.control.StateButton
        SetWindowBoundsButton        matlab.ui.control.StateButton
        LoadDecayDataButton          matlab.ui.control.Button
        ResetClearButton             matlab.ui.control.Button
        LoadIRFDataButton            matlab.ui.control.Button
        PlotData                     matlab.ui.control.UIAxes
        PlotResiduals                matlab.ui.control.UIAxes
    end

    properties (Access = public)
        RawTime                   double = []       % Time axis extracted from the IRF file (in nanoseconds)
        RawIRF                    double = []       % Instrument Response Function values
        RawData                   double = []       % TRPL decay measurement data
        ConvPadValue              double = 15       % Padding length used during convolution-based fitting
        WindowLBValue             double = 48       % Lower time bound for selecting the fit window
        WindowUBValue             double = 80       % Upper time bound for selecting the fit window
        WindowBaselineStartValue  double = 900      % Starting index of baseline range
        WindowBaselineEndValue    double = 1100     % Ending index of baseline range
        InitialGuessFit                             % Initial guesses for fit
        LowerBoundFit                               % Parameter lower bounds for fit
        UpperBoundFit                               % Parameter upper bounds for fit
        FitResults                struct = struct() % Stores the latest fit output (parameters, RMSE, etc.)
        IRFTimeShift              double = 0        % Time shift value applied to the IRF for alignment
        NumExpComponents          double = 0        % Number of exponential terms in the model
        NumPowerComponents        double = 0        % Number of power-law terms in the model
        NumSecondComponents       double = 0        % Number of second-order kinetic terms
        ModelName                 char = ''         % Descriptive name for the current model configuration
        ParameterTable            cell = {}         % Table data containing fit parameters and bounds
        FitType                                     % fittype object used for curve fitting
        FitOptions                                  % fitoptions object defining fit constraints
        FitModel                 char = ''          % Encoded string representing model type (e.g. "1exp1power")
        FitRangeClicks           double = []        % Raw click values for selecting fit region
        FitRangePatch            matlab.graphics.primitive.Patch = matlab.graphics.primitive.Patch.empty;  % Patch object for visualizing fit range
        FitRangeMarkers          matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty;  % Line markers for fit range
        BaselineRangeClicks      double = [];       % Raw click values for selecting baseline region
        BaselineRangePatch       matlab.graphics.primitive.Patch = matlab.graphics.primitive.Patch.empty;  % Patch object for visualizing baseline range
        BaselineRangeMarkers     matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty;  % Line markers for baseline range
        FitLowerSlider          matlab.ui.control.Slider  % Slider to set lower bound of fit window
        FitUpperSlider          matlab.ui.control.Slider  % Slider to set upper bound of fit window
        BaselineStartSlider     matlab.ui.control.Slider  % Slider to select baseline start time
        BaselineEndSlider       matlab.ui.control.Slider  % Slider to select baseline end time
        IRFTimeShiftEditField          matlab.ui.control.NumericEditField  % Numeric input for adjusting IRF time shift
        IRFTimeShiftEditFieldLabel     matlab.ui.control.Label             % Label for IRF time shift input
        paramgs                                     % Fit optimized parameters
        fvalgs                                      % Output of fit minimization
        ChiSq                                       % Chi-squared output of fit
        RedChiSq                                    % Reduced chi-squared output of fit
    end

    methods (Access = private)

        function startupFcn(app)
            % Maximize the app window when it first launches
            drawnow;
            app.UIFigure.WindowState = 'fullscreen';
        end

        function IRFTimeShiftEditFieldValueChanged(app, event)
            % Update the stored IRF time shift when the input field changes
            app.IRFTimeShift = app.IRFTimeShiftEditField.Value;
        end

        function isValid = validateParameterTable(app)
            % Validate all entries in the parameter table for correct structure and logic
            isValid = true;
            tableData = app.FitParametersTable.Data;

            if isempty(tableData)
                uialert(app.UIFigure, 'Parameter table is empty. Build a model first.', 'Validation Error');
                isValid = false;
                return;
            end

            for i = 1:size(tableData, 1)
                paramName = tableData{i, 1};
                initGuess = tableData{i, 2};
                lowerBound = tableData{i, 3};
                upperBound = tableData{i, 4};

                % Ensure numerical and finite initial guess
                if ~isnumeric(initGuess) || ~isfinite(initGuess)
                    uialert(app.UIFigure, sprintf('Invalid initial guess for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure lower bound is numeric and finite
                if ~isnumeric(lowerBound) || ~isfinite(lowerBound)
                    uialert(app.UIFigure, sprintf('Invalid lower bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure upper bound is valid (finite or Inf)
                if ~isnumeric(upperBound) || (~isfinite(upperBound) && ~isinf(upperBound))
                    uialert(app.UIFigure, sprintf('Invalid upper bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Validate that lower bound is strictly less than upper bound
                if lowerBound >= upperBound && isfinite(upperBound)
                    uialert(app.UIFigure, sprintf('Lower bound must be less than upper bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure initial guess falls within specified bounds
                if initGuess < lowerBound || (isfinite(upperBound) && initGuess > upperBound)
                    uialert(app.UIFigure, sprintf('Initial guess must be between bounds for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Warn if amplitude is negative
                if contains(paramName, 'Amplitude') && initGuess < 0
                    uialert(app.UIFigure, sprintf('Amplitude parameters should be positive: %s', paramName), 'Validation Warning');
                end

                % Lifetime parameters must be strictly positive
                if contains(paramName, 'Lifetime') && initGuess <= 0
                    uialert(app.UIFigure, sprintf('Lifetime parameters should be positive: %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end
            end
        end

        function buildParameterTable(app)
            % Dynamically construct the parameter table based on selected model components and data
            paramNames = {};
            initGuess = [];
            lowerBounds = [];
            upperBounds = [];

            if ~isempty(app.RawData)
                baseline = mean(app.RawData(app.RawTime < app.WindowBaselineEndValue & app.RawTime > app.WindowBaselineStartValue));
                maxData = max(app.RawData);
                maxTime = max(app.RawTime);
            else
                baseline = 10;
                maxData = 1000;
                maxTime = 100;
            end

            % Add baseline offset parameter
            paramNames{end+1} = 'Y Offset (Baseline)';
            initGuess(end+1) = baseline;
            lowerBounds(end+1) = 0;
            upperBounds(end+1) = 5 * baseline;

            % Add exponential components (amplitude and lifetime)
            for i = 1:app.NumExpComponents
                paramNames{end+1} = sprintf('Exp%d Amplitude', i);
                initGuess(end+1) = maxData / (2^i);
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Exp%d Lifetime', i);
                initGuess(end+1) = i*10 ;
                lowerBounds(end+1) = 0.2;
                upperBounds(end+1) = i*maxTime/10;
            end

            % Add power law components (amplitude, onset, alpha)
            for i = 1:app.NumPowerComponents
                paramNames{end+1} = sprintf('Power%d Amplitude', i);
                initGuess(end+1) = maxData / 2;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Power%d Onset', i);
                initGuess(end+1) = 0.1;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Power%d Alpha', i);
                initGuess(end+1) = 1.5;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = 5;
            end

            % Add second-order kinetic components (amplitude, kinetic)
            for i = 1:app.NumSecondComponents
                paramNames{end+1} = sprintf('Second%d Amplitude', i);
                initGuess(end+1) = maxData;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Second%d Kinetic', i);
                initGuess(end+1) = 0.1;
                lowerBounds(end+1) = 0.001;
                upperBounds(end+1) = Inf;
            end

            % Populate and update the parameter table
            numParams = length(paramNames);
            tableData = cell(numParams, 4);
            for i = 1:numParams
                tableData{i,1} = paramNames{i};
                tableData{i,2} = initGuess(i);
                tableData{i,3} = lowerBounds(i);
                tableData{i,4} = upperBounds(i);
                tableData{i,5} = NaN;
            end

            app.FitParametersTable.ColumnName = {'Parameter', 'Initial Guess', 'Lower Bound', 'Upper Bound', 'Fit Result'};
            app.FitParametersTable.Data = tableData;
            app.FitParametersTable.ColumnEditable = [false, true, true, true, false];
            app.ParameterTable = tableData;
        end


        function [] = runTRPLfit(app)
            %% Set things up
            Time = app.RawTime; % Define from raw to avoid issues of different vector lengths when just changing one of them and there are trailling zeros.
            IRF = app.RawIRF;
            Data = app.RawData;

            disp(['Fit model chosen: ', app.ModelName])

            %% Get rid of the trailling 0 points
            while Data(end) == 0
                Data(end) = [];
                IRF(end) = [];
                Time(end) = [];
            end

            %% Section seems unnecessary

            % Calculate number of parameters dynamically
            % Y offset (1) + Exp components (2 each) + Power components (3 each) + Second components (2 each) + IRF shift (1)
            %numParam = 1 + app.NumExpComponents*2 + app.NumPowerComponents*3 + app.NumSecondComponents*2 + 1;

            %% Restrict to fit window
            TimeZero = Time(max(Data)==Data);

            WindowData = Data(Time>app.WindowLBValue-app.ConvPadValue & Time<app.WindowUBValue+app.ConvPadValue);
            WindowIRF = IRF(Time>app.WindowLBValue-app.ConvPadValue & Time<app.WindowUBValue+app.ConvPadValue);
            WindowTime = Time(Time>app.WindowLBValue-app.ConvPadValue & Time<app.WindowUBValue+app.ConvPadValue);

            %% Setup fit function.
            TRPLfitfun = @(param)Fit_IRF_General(app,param,WindowTime,WindowData,WindowIRF,TimeZero);

            %% Global minimization

            %options = optimoptions(@fmincon);
            problem = createOptimProblem('fmincon','objective',TRPLfitfun,'x0',app.InitialGuessFit,...
                'lb',app.LowerBoundFit,'ub',app.UpperBoundFit);
            problem.options.Display = 'iter';

            % [paramgs,fvalgs,eflag,output] = fmincon(problem); %Test structure without multistart

            ms = MultiStart;
            ms.UseParallel = false;
            ms.StartPointsToRun = 'all';
            ms.XTolerance = 1E-8;
            ms.FunctionTolerance = 1E-8;
            numMultiStartRuns = app.numMultiStart.Value;

            tic                 % for timing
            [app.paramgs,app.fvalgs] = run(ms,problem,numMultiStartRuns);
            toc

            %% Generate best fit data

            [FinalFit, componentData] = Decay_IRF_General(app,WindowTime,WindowIRF,TimeZero);

            % Extract individual components for plotting
            compIdx = 1;
            if app.NumExpComponents >= 1
                FitYdataExp = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumExpComponents >= 2
                FitYdataExp2 = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumExpComponents >= 3
                FitYdataExp3 = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumPowerComponents >= 1
                FitYdataPower = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumPowerComponents >= 2
                FitYdataPower2 = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumPowerComponents >= 3
                FitYdataPower3 = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumSecondComponents >= 1
                FitYdataSecond = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumSecondComponents >= 2
                FitYdataSecond2 = componentData{compIdx};
                compIdx = compIdx + 1;
            end
            if app.NumSecondComponents >= 3
                FitYdataSecond3 = componentData{compIdx};
                compIdx = compIdx + 1;
            end

            % Calculate the Chi2 from within the fit window, which is what is outputted
            % by the Decay functions.Keep in mind that the WindowTime, WindowIRF, and
            % WindowData have 'padding' defined by app.ConvPadValue.

            % 1) build the window mask
            winMask = (WindowTime > WindowTime(1)+app.ConvPadValue) & (WindowTime < WindowTime(end)-app.ConvPadValue);

            % 2) extract the vectors you need
            timeWin = WindowTime(winMask);
            dataWin = WindowData(winMask);
            fitWin  = FinalFit;   % FinalFit is already filtered inside your Decay function

            % 3) sanity-check their lengths
            if numel(timeWin) ~= numel(fitWin)
                error('WindowTime (%d) and FinalFit (%d) lengths do not match.', numel(timeWin), numel(fitWin));
            end

            % 4) drop any zeros in the fit
            keep   = fitWin ~= 0;
            CleanTime     = timeWin(keep);
            CleanData     = dataWin(keep);
            CleanFinalFit = fitWin(keep);

            FitDelta = CleanData - CleanFinalFit;
            FitRes = FitDelta./sqrt(CleanData);
            app.ChiSq = sum(FitDelta.^2./CleanFinalFit);
            app.RedChiSq = app.ChiSq/(length(CleanData)-length(app.InitialGuessFit)-1);        % Length Initial Guess gives you the number of fitting parameters.

            %% Plot ouputs
            cla(app.PlotData);
            semilogy(app.PlotData,Time,Data)
            hold(app.PlotData,"on")
            semilogy(app.PlotData,CleanTime,CleanFinalFit,'LineWidth',0.75)
            yline(app.PlotData,app.paramgs(1),'--k')

            % Dynamic plotting based on components
            legendEntries = {'Data','Total Fit','Baseline'};

            % Plot exponential components
            for i = 1:app.NumExpComponents
                if i == 1 && exist('FitYdataExp', 'var')
                    plot(app.PlotData,CleanTime,FitYdataExp)
                    if app.NumExpComponents == 1
                        legendEntries{end+1} = 'Exponential comp.';
                    else
                        legendEntries{end+1} = 'Exponential comp. 1';
                    end
                elseif i == 2 && exist('FitYdataExp2', 'var')
                    plot(app.PlotData,CleanTime,FitYdataExp2)
                    legendEntries{end+1} = 'Exponential comp. 2';
                elseif i == 3 && exist('FitYdataExp3', 'var')
                    plot(app.PlotData,CleanTime,FitYdataExp3)
                    legendEntries{end+1} = 'Exponential comp. 3';
                end
            end

            % Plot power law components
            for i = 1:app.NumPowerComponents
                if i == 1 && exist('FitYdataPower', 'var')
                    plot(app.PlotData,CleanTime,FitYdataPower)
                    if app.NumPowerComponents == 1
                        legendEntries{end+1} = 'Power law comp.';
                    else
                        legendEntries{end+1} = 'Power law comp. 1';
                    end
                elseif i == 2 && exist('FitYdataPower2', 'var')
                    plot(app.PlotData,CleanTime,FitYdataPower2)
                    legendEntries{end+1} = 'Power law comp. 2';
                elseif i == 3 && exist('FitYdataPower3', 'var')
                    plot(app.PlotData,CleanTime,FitYdataPower3)
                    legendEntries{end+1} = 'Power law comp. 3';
                end
            end

            % Plot second order components
            for i = 1:app.NumSecondComponents
                if i == 1 && exist('FitYdataSecond', 'var')
                    plot(app.PlotData,CleanTime,FitYdataSecond)
                    if app.NumSecondComponents == 1
                        legendEntries{end+1} = 'Second ord. comp.';
                    else
                        legendEntries{end+1} = 'Second ord. comp. 1';
                    end
                elseif i == 2 && exist('FitYdataSecond2', 'var')
                    plot(app.PlotData,CleanTime,FitYdataSecond2)
                    legendEntries{end+1} = 'Second ord. comp. 2';
                elseif i == 3 && exist('FitYdataSecond3', 'var')
                    plot(app.PlotData,CleanTime,FitYdataSecond3)
                    legendEntries{end+1} = 'Second ord. comp. 3';
                end
            end
            legend(app.PlotData,legendEntries,'Box','off','FontSize',8,'NumColumns',2)
            xlim(app.PlotData,[app.WindowLBValue app.WindowUBValue])
            hold(app.PlotData,"off")

            cla(app.PlotResiduals)
            plot(app.PlotResiduals,CleanTime,FitRes)
            yline(app.PlotResiduals,0)
            xlim(app.PlotResiduals,[app.WindowLBValue app.WindowUBValue])


            %% Output additional parameters from the fit

            % Find t50% from fit
            SearchTime = CleanTime(CleanTime>TimeZero);
            SearchData = CleanFinalFit(CleanTime>TimeZero);

            HalfIntTimes = SearchTime(min(abs(SearchData-max(SearchData)/2))==abs(SearchData-max(SearchData)/2));
            RoughHalfIntTime = mean(HalfIntTimes);              %In case there are multiple values

            DataSpacing = CleanTime(end)-CleanTime(end-1);
            FineTime = RoughHalfIntTime-2*DataSpacing:DataSpacing/50:RoughHalfIntTime+2*DataSpacing;
            FineData = interp1(CleanTime,CleanTime,FineTime);
            FinalHalfIntTime = FineTime(min(abs(FineData-max(SearchData)/2))==abs(FineData-max(SearchData)/2));

            t50 = FinalHalfIntTime(1) - TimeZero;              %TimeZero is defined as the time where the Ydata is max

            % Find relative emission from the different components
            % Needs to be edited to be programmatic.
            switch app.FitModel
                case '1exp'
                    RelEmExp = 1;           % Trivial since there is only 1 component.

                case '1power'
                    RelEmPower = 1;         % Trivial since there is only 1 component.

                case '1exp1power'
                    ExpCompIntegral = trapz(CleanTime,FitYdataExp);
                    PowerCompIntegral = trapz(CleanTime,FitYdataPower);

                    RelEmExp = ExpCompIntegral/(ExpCompIntegral+PowerCompIntegral);
                    RelEmPower = PowerCompIntegral/(ExpCompIntegral+PowerCompIntegral);

                case '2exp'
                    Exp1CompIntegral = trapz(CleanTime,FitYdataExp);
                    Exp2CompIntegral = trapz(CleanTime,FitYdataExp2);

                    RelEmExp1 = Exp1CompIntegral/(Exp1CompIntegral+Exp2CompIntegral);
                    RelEmExp2 = Exp2CompIntegral/(Exp1CompIntegral+Exp2CompIntegral);

                case '2exp1power'
                    Exp1CompIntegral = trapz(CleanTime,FitYdataExp);
                    Exp2CompIntegral = trapz(CleanTime,FitYdataExp2);
                    PowerCompIntegral = trapz(CleanTime,FitYdataPower);

                    RelEmExp1 = Exp1CompIntegral/(Exp1CompIntegral+Exp2CompIntegral+PowerCompIntegral);
                    RelEmExp2 = Exp2CompIntegral/(Exp1CompIntegral+Exp2CompIntegral+PowerCompIntegral);
                    RelEmPower = PowerCompIntegral/(Exp1CompIntegral+Exp2CompIntegral+PowerCompIntegral);

                case '1exp1second'
                    ExpCompIntegral = trapz(CleanTime,FitYdataExp);
                    SecondCompIntegral = trapz(CleanTime,FitYdataSecond);

                    RelEmExp = ExpCompIntegral/(ExpCompIntegral+SecondCompIntegral);
                    RelEmSecond = SecondCompIntegral/(ExpCompIntegral+SecondCompIntegral);

                case '2exp1second'

                    Exp1CompIntegral = trapz(CleanTime,FitYdataExp);
                    Exp2CompIntegral = trapz(CleanTime,FitYdataExp2);
                    SecondCompIntegral = trapz(CleanTime,FitYdataSecond);

                    RelEmExp1 = Exp1CompIntegral/(Exp1CompIntegral+Exp2CompIntegral+SecondCompIntegral);
                    RelEmExp2 = Exp2CompIntegral/(Exp1CompIntegral+Exp2CompIntegral+SecondCompIntegral);
                    RelEmSecond = SecondCompIntegral/(Exp1CompIntegral+Exp2CompIntegral+SecondCompIntegral);

            end
        end

        function RedChiSq = Fit_IRF_General(app,param,Time,Data,IRF,TimeZero)
            % Generalized fit function with experimental IRF convolution
            % Supports arbitrary numbers of exponential, power law, and second order components

            % Set up parameters
            t = Time;
            paramIdx = 1;

            % Y offset (always first parameter)
            Y0 = param(paramIdx);
            paramIdx = paramIdx + 1;

            %Find the index of the first component of where the 'non-zero' time data is
            NnegX = find(t<TimeZero,1,'last');

            % IRF shift (always last parameter)
            IRFshift = param(end);

            %Get the IRF values at the shifted time
            ShiftedIRF = interp1(t,IRF,t+IRFshift,"pchip");

            % Initialize total signal
            GuessKin = Y0;

            % Process exponential components
            for i = 1:app.NumExpComponents
                ExpAmp = param(paramIdx);
                ExpTau = param(paramIdx+1);
                paramIdx = paramIdx + 2;

                % Generate exponential component
                TempYExp = ExpAmp.*exp(-(t-TimeZero)./ExpTau);
                TempYExp(t<TimeZero) = 0;
                ConvYExp = conv(TempYExp,ShiftedIRF/sum(ShiftedIRF));
                YdataExp = ConvYExp(1,NnegX+1:length(t)+NnegX);

                GuessKin = GuessKin + YdataExp;
            end

            % Process power law components
            for i = 1:app.NumPowerComponents
                PowerAmp = param(paramIdx);
                PowerOnset = param(paramIdx+1);
                PowerAlpha = param(paramIdx+2);
                paramIdx = paramIdx + 3;

                % Generate power law component
                TempYPL = PowerAmp./(((1+(t-TimeZero)/PowerOnset)).^PowerAlpha);
                TempYPL(t<TimeZero) = 0;
                ConvYPL = conv(TempYPL,ShiftedIRF/sum(ShiftedIRF));
                YdataPL = ConvYPL(1,NnegX+1:length(t)+NnegX);

                GuessKin = GuessKin + YdataPL;
            end

            % Process second order components
            for i = 1:app.NumSecondComponents
                SecondAmp = param(paramIdx);
                SecondKin = param(paramIdx+1);
                paramIdx = paramIdx + 2;

                % Generate second order component
                TempYSecond = SecondAmp./((1+(t-TimeZero)/SecondKin));
                TempYSecond(t<TimeZero) = 0;
                ConvYSecond = conv(TempYSecond,ShiftedIRF/sum(ShiftedIRF));
                YdataSecond = ConvYSecond(1,NnegX+1:length(t)+NnegX);

                GuessKin = GuessKin + YdataSecond;
            end

            % Calculate Chi squared taking into consideration the padding
            GuessKinWindow = GuessKin(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);
            DataWindow = Data(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);

            N = length(DataWindow);
            app.ChiSq = ((DataWindow-GuessKinWindow).^2)./abs(GuessKinWindow);
            RedChiSq = sum(app.ChiSq)/(N-length(param)-1);
            app.RedChiSq = RedChiSq;

        end

        function [TotalOutput, componentData] = Decay_IRF_General(app,Time,IRF,TimeZero)
            % Generalized decay function with experimental IRF convolution
            % Returns total fit and individual components

            % Set up parameters
            t = Time;
            paramIdx = 1;

            % Y offset (always first parameter)
            Y0 = app.paramgs(paramIdx);
            paramIdx = paramIdx + 1;

            %Find the index of the first component of where the 'non-zero' time data is
            NnegX = find(t<TimeZero,1,'last');

            % IRF shift (always last parameter)
            IRFshift = app.paramgs(end);

            %Get the IRF values at the shifted time
            ShiftedIRF = interp1(t,IRF,t+IRFshift,"pchip");

            % Initialize total output (full length first)
            TotalOutput = Y0;
            componentData = {};

            % Process exponential components
            for i = 1:app.NumExpComponents
                ExpAmp = app.paramgs(paramIdx);
                ExpTau = app.paramgs(paramIdx+1);
                paramIdx = paramIdx + 2;

                % Generate exponential component
                TempYExp = ExpAmp.*exp(-(t-TimeZero)./ExpTau);
                TempYExp(t<TimeZero) = 0;
                ConvYExp = conv(TempYExp,ShiftedIRF/sum(ShiftedIRF));
                YdataExp = ConvYExp(1,NnegX+1:length(t)+NnegX);

                % Add to total (before filtering)
                TotalOutput = TotalOutput + YdataExp;

                % Apply window filtering for component storage
                YdataExpFiltered = YdataExp(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);
                componentData{end+1} = YdataExpFiltered;
            end

            % Process power law components
            for i = 1:app.NumPowerComponents
                PowerAmp = app.paramgs(paramIdx);
                PowerOnset = app.paramgs(paramIdx+1);
                PowerAlpha = app.paramgs(paramIdx+2);
                paramIdx = paramIdx + 3;

                % Generate power law component
                TempYPL = PowerAmp./(((1+(t-TimeZero)/PowerOnset)).^PowerAlpha);
                TempYPL(t<TimeZero) = 0;
                ConvYPL = conv(TempYPL,ShiftedIRF/sum(ShiftedIRF));
                YdataPL = ConvYPL(1,NnegX+1:length(t)+NnegX);

                % Add to total (before filtering)
                TotalOutput = TotalOutput + YdataPL;

                % Apply window filtering for component storage
                YdataPLFiltered = YdataPL(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);
                componentData{end+1} = YdataPLFiltered;
            end

            % Process second order components
            for i = 1:app.NumSecondComponents
                SecondAmp = app.paramgs(paramIdx);
                SecondKin = app.paramgs(paramIdx+1);
                paramIdx = paramIdx + 2;

                % Generate second order component
                TempYSecond = SecondAmp./((1+(t-TimeZero)/SecondKin));
                TempYSecond(t<TimeZero) = 0;
                ConvYSecond = conv(TempYSecond,ShiftedIRF/sum(ShiftedIRF));
                YdataSecond = ConvYSecond(1,NnegX+1:length(t)+NnegX);

                % Add to total (before filtering)
                TotalOutput = TotalOutput + YdataSecond;

                % Apply window filtering for component storage
                YdataSecondFiltered = YdataSecond(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);
                componentData{end+1} = YdataSecondFiltered;
            end

            % Apply window filtering to total output (do this LAST)
            TotalOutput = TotalOutput(Time > Time(1)+app.ConvPadValue & Time < Time(end)-app.ConvPadValue);

        end
    end

    methods (Access = public)

        function drawFitRangePatch(app, bounds)
            % Render a shaded region on the plot representing the fitting window
            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                delete(app.FitRangePatch);
            end
            yl = app.PlotData.YLim;
            app.FitRangePatch = patch(app.PlotData, ...
                [bounds(1) bounds(2) bounds(2) bounds(1)], ...
                [yl(1) yl(1) yl(2) yl(2)], ...
                [0.3 0.3 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
            uistack(app.FitRangePatch, 'bottom');
        end

        function drawBaselineRangePatch(app, bounds)
            % Render a shaded region on the plot representing the baseline region
            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                delete(app.BaselineRangePatch);
            end
            yl = app.PlotData.YLim;
            app.BaselineRangePatch = patch(app.PlotData, ...
                [bounds(1) bounds(2) bounds(2) bounds(1)], ...
                [yl(1) yl(1) yl(2) yl(2)], ...
                [0.7 0.3 0.3], 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            uistack(app.BaselineRangePatch, 'bottom');
        end

        function updateWindowLB(app, src, ~)
            % Smoothly update fit‑window lower bound as the left drawline moves
            x = src.Position(1,1);
            app.WindowLowerBoundnsEditField.Value = x;
            app.WindowLBValue = x;

            % If the shaded patch already exists, update its vertices in place
            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                hHigh = findobj(app.PlotData, 'Tag', 'windowLineHigh');
                ub = hHigh.Position(1,1);
                yl = app.PlotData.YLim;
                set(app.FitRangePatch, ...
                    'XData', [x ub ub x], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateWindowUB(app, src, ~)
            % Smoothly update fit‑window upper bound as the right drawline moves
            x = src.Position(1,1);
            app.WindowUpperBoundnsEditField.Value = x;
            app.WindowUBValue = x;

            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                hLow = findobj(app.PlotData, 'Tag', 'windowLineLow');
                lb = hLow.Position(1,1);
                yl = app.PlotData.YLim;
                set(app.FitRangePatch, ...
                    'XData', [lb x x lb], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateBaselineStart(app, src, ~)
            % Respond to dragging of the baseline start marker and update index
            % Smoothly update baseline start index & shaded region when dragging left baseline line
            x = src.Position(1,1);
            app.WindowBaselineStartindexEditField.Value = x;
            app.WindowBaselineStartValue = x;

            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                hHigh = findobj(app.PlotData, 'Tag', 'baselineLineHigh');
                xEnd = hHigh.Position(1,1);
                yl = app.PlotData.YLim;
                set(app.BaselineRangePatch, ...
                    'XData', [x xEnd xEnd x], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateBaselineEnd(app, src, ~)
            % Smoothly update baseline end index & shaded region when dragging right baseline line
            x = src.Position(1,1);
            app.WindowBaselineEndindexEditField.Value = x;
            app.WindowBaselineEndValue = x;

            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                hLow = findobj(app.PlotData, 'Tag', 'baselineLineLow');
                xStart = hLow.Position(1,1);
                yl = app.PlotData.YLim;
                set(app.BaselineRangePatch, ...
                    'XData', [xStart x x xStart], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIRFDataButton
        function LoadIRFDataButtonPushed(app, event)
            % Open file selection dialog for user to choose an IRF Excel file
            [irf_file, irf_path] = uigetfile( ...
                {'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
                'Select IRF Excel File');
            if isequal(irf_file,0)
                uialert(app.UIFigure,'IRF file selection canceled.','File Error');
                return
            end

            try
                % Construct full file path from selected name and path
                fname = fullfile(irf_path, irf_file);

                % Read the entire Excel sheet as raw cell array for header inspection
                rawCells = readcell(fname);

                % Locate the first row where any cell contains a ':' (assumed header row marker)
                hasColon = cellfun(@(x) ischar(x) && contains(x, ':'), rawCells);
                headerRow = find(any(hasColon,2), 1, 'first');
                if isempty(headerRow)
                    error('Could not find any row containing '':'' to use as header.');
                end

                % Read the table starting from the identified header row
                tblIRF = readtable(fname, ...
                    'PreserveVariableNames', true, ...
                    'ReadVariableNames',    true, ...
                    'HeaderLines',          headerRow-1);

                % Prompt user to select which column corresponds to time
                columnNames = tblIRF.Properties.VariableNames;
                [timeIdx, ok1] = listdlg( ...
                    'PromptString','Select Time column:', ...
                    'ListString', columnNames, ...
                    'SelectionMode','single',    ...
                    'ListSize',[300,200]);
                if ~ok1, return; end

                % Prompt user to select which column corresponds to IRF signal
                [irfIdx, ok2] = listdlg( ...
                    'PromptString','Select IRF column:', ...
                    'ListString', columnNames, ...
                    'SelectionMode','single',    ...
                    'ListSize',[300,200]);
                if ~ok2, return; end

                % Store selected columns as RawTime and RawIRF in app state
                app.RawTime = tblIRF{:,timeIdx}(:)';
                app.RawIRF  = tblIRF{:,irfIdx}(:)';

                % Clear previous plot and display the newly loaded IRF data
                cla(app.PlotData);
                plot(app.PlotData, app.RawTime, app.RawIRF);
                title(app.PlotData, 'Instrument Response (IRF)');
                xlabel(app.PlotData, 'Time (ns)');
                ylabel(app.PlotData, 'IRF Amplitude');

                % Notify user that IRF data was successfully loaded
                uialert(app.UIFigure,'IRF data loaded successfully!','Success');
            catch ME
                % Display error message if loading or processing fails
                uialert(app.UIFigure, ME.message, 'Load Error');
            end

        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            % Gray out the Fit button immediately
            app.FitButton.Enable = 'off';
            drawnow;

            % 1) Require IRF & decay data
            if isempty(app.RawTime) || isempty(app.RawIRF) || isempty(app.RawData)
                uialert(app.UIFigure,'Load IRF and decay first.','Error');
                app.FitButton.Enable = 'on';
                return
            end

            % 2) Require a built model
            if isempty(app.ModelName) || isempty(app.ParameterTable)
                uialert(app.UIFigure,'Build a model first using "Build Model" button.','Error');
                app.FitButton.Enable = 'on';
                return
            end

            % 3) Validate the parameter table
            if ~app.validateParameterTable()
                app.FitButton.Enable = 'on';
                return
            end


            % Determine fit-window bounds
            lb = app.WindowLowerBoundnsEditField.Value;
            if isempty(lb) || lb <= 0
                ...;
            else
                WindowLB = app.WindowLBValue;
            end

            ub = app.WindowUpperBoundnsEditField.Value;
            if isempty(ub) || ub <= 0
                ...
            else
                WindowUB = app.WindowUBValue;
            end

            % Determine baseline range
            sb = app.WindowBaselineStartindexEditField.Value;
            eb = app.WindowBaselineEndindexEditField.Value;
            dataLength = numel(app.RawData);
            if isempty(sb) || isempty(eb) || sb <= 0 || eb <= 0 || sb > dataLength || eb > dataLength
                startIdx = max(1, round(dataLength * 0.9));
                endIdx   = dataLength;
                app.WindowBaselineStartindexEditField.Value = app.RawTime(startIdx);
                app.WindowBaselineEndindexEditField.Value   = app.RawTime(endIdx);
                app.WindowBaselineStartValue = app.RawTime(startIdx);
                app.WindowBaselineEndValue   = app.RawTime(endIdx);
            end


            % Extract table-based guesses & bounds
            tableData   = app.FitParametersTable.Data;
            nParams     = size(tableData,1);
            app.InitialGuessFit = zeros(1,nParams+1);
            app.LowerBoundFit   = zeros(1,nParams+1);
            app.UpperBoundFit   = zeros(1,nParams+1);
            for i = 1:nParams
                app.InitialGuessFit(i) = tableData{i,2};
                app.LowerBoundFit(i)   = tableData{i,3};
                app.UpperBoundFit(i)   = tableData{i,4};
            end

            % --- CORRECTED LINE: use the app.IRFTimeShift property ---
            app.InitialGuessFit(end) = app.IRFTimeShift;
            app.LowerBoundFit(end)   = -3;
            app.UpperBoundFit(end)   = 3;


            try runTRPLfit(app);
            catch ME
                uialert(app.UIFigure, sprintf('Fitting error: %s', ME.message), 'Fit Error');
                app.FitButton.Enable = 'on';
                return
            end


                      % Save into app.FitResults
            res = struct( ...
                'RawTime',app.RawTime, ...
                'RawIRF',app.RawIRF, ...
                'RawData',app.RawData, ...
                'ConvPad',app.ConvPadValue, ...
                'WindowLB',app.WindowLBValue, ...
                'WindowUB',app.WindowUBValue, ...
                'WindowBaselineStart',app.WindowBaselineStartValue, ...
                'WindowBaselineEnd',app.WindowBaselineEndValue, ...
                'ModelName',app.FitModel, ...
                'NumExpComponents',app.NumExpComponents, ...
                'NumPowerComponents',app.NumPowerComponents, ...
                'NumSecondComponents',app.NumSecondComponents, ...
                'paramgs', app.paramgs,...
                'fvalgs', app.fvalgs, ...
                'ChiSq', app.ChiSq, ...
                'RedChiSq', app.RedChiSq ...
                );

            app.FitResults = res;


            % Add the fit results into the parameter table
            numParams = size(app.FitParametersTable.Data,1);
            for i = 1:numParams
                app.FitParametersTable.Data{i,5} = app.paramgs(i);
            end

            % Give goodness of fit results in the plot title
            title(app.PlotData,['IRF shift of ' num2str(app.paramgs(end),3), ...
                '. Chi-squared = ' num2str(app.ChiSq,3), '. Reduced Chi-squared = ' num2str(app.RedChiSq,3)])

            % Re-enable the Fit button
            app.FitButton.Enable = 'on';


        end

        % Callback function
        function ConvPadnsEditFieldValueChanged(app, event)
            app.ConvPadValue = app.ConvPadnsEditField.Value;


        end

        % Button pushed function: ExportDataasmatButton
        function ExportDataasmatButtonPushed(app, event)
            % Check if fit results are available before attempting export
            if isempty(fieldnames(app.FitResults))
                uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
                return
            end

            % Prompt user to choose a .mat file location for saving the results
            [file, path] = uiputfile('*.mat', 'Save results as');
            if isequal(file, 0)
                return;  % Exit if user cancels the file save dialog
            end

            % Save the fit results structure to the specified .mat file
            filename = fullfile(path, file);
            tmp = app.FitResults;
            save(filename, '-struct', 'tmp');

            % Inform the user that the export was successful
            uialert(app.UIFigure, sprintf('Results saved to\n%s', filename), 'Export Success');


        end

        % Value changed function: WindowUpperBoundnsEditField
        function WindowUpperBoundnsEditFieldValueChanged(app, event)
            app.WindowUBValue = app.WindowUpperBoundnsEditField.Value;

        end

        % Value changed function: WindowLowerBoundnsEditField
        function WindowLowerBoundnsEditFieldValueChanged(app, event)
            app.WindowLBValue = app.WindowLowerBoundnsEditField.Value;


        end

        % Value changed function: WindowBaselineStartindexEditField
        function WindowBaselineStartindexEditFieldValueChanged(app, event)
            app.WindowBaselineStartValue = app.WindowBaselineStartindexEditField.Value;

        end

        % Value changed function: WindowBaselineEndindexEditField
        function WindowBaselineEndindexEditFieldValueChanged(app, event)
            app.WindowBaselineEndValue = app.WindowBaselineEndindexEditField.Value;

        end

        % Button pushed function: ExportTRPLDecayasFigButton
        function ExportTRPLDecayasFigButtonPushed(app, event)

            % Check that fitting results exist before proceeding with export
            if isempty(fieldnames(app.FitResults))
                uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
                return
            end

            % Prompt the user to select a file format for exporting the TRPL decay plot
            if ispc
                formats = {'PDF','.fig','EMF'};
            else
                formats = {'PDF','.fig','SVG'};
            end
            [fIdx, ok] = listdlg( ...
                'PromptString','Select TRPL-decay export format:', ...
                'ListString',formats, ...
                'SelectionMode','single' );
            if ~ok, return, end

            % Normalize format selection and get file extension
            fmt = formats{fIdx};
            ext = lower(strrep(fmt,'.',''));

            % Prompt user to choose file name and location for saving
            filter = {['*.' ext], [upper(ext) ' Files (*.' ext ')']};
            [file, path] = uiputfile(filter, 'Save TRPL decay graph as');
            if isequal(file,0), return, end

            % Append extension to file name if not already present
            [~, ~, fext] = fileparts(file);
            if ~strcmpi(fext, ['.' ext])
                file = [file '.' ext];
            end
            fname = fullfile(path, file);

            % Perform export based on selected format
            switch ext
                case {'pdf','emf','svg'}
                    % Export the plot using vector graphics
                    exportgraphics(app.PlotData, fname, 'ContentType','vector');

                case 'fig'
                    % Export as .fig: copy axes to a temporary figure before saving
                    tmpf = figure('Visible','off');
                    axCopy = copyobj(app.PlotData, tmpf);
                    set(axCopy, 'Units','normalized', 'Position',[0.13 0.11 0.775 0.815]);

                    % Copy legend if it exists
                    if ~isempty(app.PlotData.Legend)
                        lg = legend(axCopy, app.PlotData.Legend.String);
                        lg.Location = app.PlotData.Legend.Location;
                    end

                    % Save and close the temporary figure
                    tmpf.Visible = 'on';
                    saveas(tmpf, fname);
                    close(tmpf);

                otherwise
                    % Handle unexpected or unsupported format types
                    uialert(app.UIFigure,'Unknown format selected.','Export Error');
                    return
            end

            % Notify user that the export was completed successfully
            uialert(app.UIFigure, sprintf('TRPL decay graph saved to\n%s', fname), 'Export Success');


        end

        % Button pushed function: ExportResidualsasFigButton
        function ExportResidualsasFigButtonPushed(app, event)
            % Check that fit results exist before attempting to export residuals
            if isempty(fieldnames(app.FitResults))
                uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
                return
            end

            % Prompt the user to select a file format for exporting the residuals plot
            if ispc
                formats = {'PDF','.fig','EMF'};
            else
                formats = {'PDF','.fig','SVG'};
            end
            [fIdx, ok] = listdlg( ...
                'PromptString','Select residuals-export format:', ...
                'ListString',formats, ...
                'SelectionMode','single' );
            if ~ok, return, end

            % Normalize and parse selected format
            fmt = formats{fIdx};
            ext = lower(strrep(fmt,'.',''));

            % Prompt user for save location and filename
            filter = {['*.' ext], [upper(ext) ' Files (*.' ext ')']};
            [file, path] = uiputfile(filter, 'Save residuals graph as');
            if isequal(file,0), return, end

            % Ensure file has correct extension
            [~, ~, fext] = fileparts(file);
            if ~strcmpi(fext, ['.' ext])
                file = [file '.' ext];
            end
            fname = fullfile(path, file);

            % Export residuals plot using selected format and app.PlotResiduals
            switch ext
                case {'pdf','emf','svg'}
                    % Export as vector format directly from residuals axes
                    exportgraphics(app.PlotResiduals, fname, 'ContentType','vector');

                case 'fig'
                    % Create a temporary figure and copy residuals axes into it
                    tmpf = figure('Visible','off');
                    axCopy = copyobj(app.PlotResiduals, tmpf);
                    set(axCopy, 'Units','normalized', 'Position',[0.13 0.11 0.775 0.815]);

                    % Recreate legend if it exists
                    if ~isempty(app.PlotResiduals.Legend)
                        lg = legend(axCopy, app.PlotResiduals.Legend.String);
                        lg.Location = app.PlotResiduals.Legend.Location;
                    end

                    % Save and close the temporary figure
                    tmpf.Visible = 'on';
                    saveas(tmpf, fname);
                    close(tmpf);

                otherwise
                    % Show error for unsupported formats
                    uialert(app.UIFigure,'Unknown format selected.','Export Error');
                    return
            end

            % Notify user that export completed successfully
            uialert(app.UIFigure, sprintf('Residuals graph saved to\n%s', fname), 'Export Success');

        end

        % Button pushed function: LoadDecayDataButton
        function LoadDecayDataButtonPushed(app, event)
            % Require IRF data to be loaded before allowing decay import
            if isempty(app.RawTime)
                uialert(app.UIFigure, ...
                    'Please load IRF data before loading decay data.', ...
                    'Data Missing');
                return
            end

            % Prompt user to select a TRPL decay Excel file
            [decay_file, decay_path] = uigetfile( ...
                {'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
                'Select TRPL Decay Excel File');
            if isequal(decay_file,0)
                uialert(app.UIFigure,'Decay file selection canceled.','File Error');
                return
            end

            try
                % Construct full path to the selected file
                fname = fullfile(decay_path, decay_file);

                % Read entire worksheet as raw cells to detect header structure
                rawCells = readcell(fname);

                % Search for the first row containing a colon (used to infer header row)
                hasColon = cellfun(@(x) ischar(x) && contains(x, ':'), rawCells);
                headerRow = find(any(hasColon,2), 1, 'first');

                % Read table using inferred or default header row
                if ~isempty(headerRow)
                    tblData = readtable(fname, ...
                        'PreserveVariableNames', true, ...
                        'ReadVariableNames',    true, ...
                        'HeaderLines',          headerRow-1);
                else
                    tblData = readtable(fname);  % default: assume first row is header
                end

                % Prompt user to select columns for time and decay data (auto-select if only one column exists)
                columnNames = tblData.Properties.VariableNames;
                if numel(columnNames) == 1
                    timeCol = 1;
                    dataCol = 1;
                else
                    [timeIdx, ok1] = listdlg( ...
                        'PromptString','Select Time column:', ...
                        'ListString',    columnNames, ...
                        'SelectionMode', 'single', ...
                        'ListSize',[300,200]);
                    if ~ok1, return; end

                    [dataIdx, ok2] = listdlg( ...
                        'PromptString','Select Decay data column:', ...
                        'ListString',    columnNames, ...
                        'SelectionMode', 'single', ...
                        'ListSize',[300,200]);
                    if ~ok2, return; end

                    timeCol = timeIdx;
                    dataCol = dataIdx;
                end

                % Extract time and decay data from selected columns
                timeData = tblData{:,timeCol}(:)';
                rawData  = tblData{:,dataCol}(:)';

                % Interpolate decay data to match IRF time axis if needed
                if numel(timeData) ~= numel(app.RawTime) || any(timeData ~= app.RawTime)
                    app.RawData = interp1(timeData, rawData, app.RawTime, 'pchip');
                else
                    app.RawData = rawData;
                end

                % Plot IRF and decay data on a shared semilog Y-axis
                cla(app.PlotData);
                hold(app.PlotData,'on');
                semilogy(app.PlotData, app.RawTime, app.RawIRF,  '-', 'DisplayName', 'IRF');
                semilogy(app.PlotData, app.RawTime, app.RawData, '--', 'DisplayName', 'TRPL Decay');
                hold(app.PlotData,'off');
                title(app.PlotData, 'IRF and TRPL Decay');
                xlabel(app.PlotData, 'Time (ns)');
                ylabel(app.PlotData, 'Counts');
                legend(app.PlotData, 'Location', 'best');
                app.PlotData.YScale = 'log';

                % Reset stored click coordinates used for interactive range setting
                app.FitRangeClicks = [];

                % Confirm successful load to user
                uialert(app.UIFigure,'Decay data loaded and overlaid!','Success');
            catch ME
                % Display error message if loading fails
                uialert(app.UIFigure, ME.message, 'Load Error');
            end

        end

        % Callback function
        function ExportResidualsMACONLYButtonPushed(app, event)

        end

        % Callback function
        function ExportTRPLDecayMACONLYButtonPushed(app, event)

        end

        % Callback function
        function OneFileButtonPushed(app, event)
            %  Make sure IRF is loaded first
            if isempty(app.RawTime)
                uialert(app.UIFigure, 'Please load IRF data first.', 'Error');
                return
            end

            %  Prompt for Excel file
            [file, path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
                'Select Excel File with TRPL Traces');
            if isequal(file,0)
                return  % user canceled
            end
            fname = fullfile(path, file);

            %  Get sheet names
            [~, sheetNames] = xlsfinfo(fname);
            if isempty(sheetNames)
                uialert(app.UIFigure, 'No sheets found in file.', 'Read Error');
                return
            end
            if numel(sheetNames)>1
                [sIdx, ok] = listdlg( ...
                    'PromptString','Select a sheet:', ...
                    'ListString',sheetNames, ...
                    'SelectionMode','single' );
                if ~ok, return, end
                sheet = sheetNames{sIdx};
            else
                sheet = sheetNames{1};
            end

            %  Read the chosen sheet
            T = readtable(fname, 'Sheet', sheet, 'PreserveVariableNames', true);

            % Pick which column is the decay trace
            vars = T.Properties.VariableNames;
            if numel(vars)>2
                [vIdx, ok2] = listdlg( ...
                    'PromptString','Select TRPL decay column:', ...
                    'ListString',vars(2:end), ...
                    'SelectionMode','single' );
                if ~ok2, return, end
                dataVar = vars{vIdx+1};
            else
                dataVar = vars{2};
            end

            %  Extract and (if needed) interpolate
            timeData = T{:,1}(:)';
            rawData  = T{:,dataVar}(:)';
            if numel(timeData)~=numel(app.RawTime) || any(timeData~=app.RawTime)
                app.RawData = interp1(timeData, rawData, app.RawTime, 'pchip');
            else
                app.RawData = rawData;
            end

            %  Plot
            cla(app.PlotResiduals);
            plot(app.PlotResiduals, app.RawTime, app.RawData);
            title(app.PlotResiduals, 'TRPL Decay');
            xlabel(app.PlotResiduals, 'Time (ns)');
            ylabel(app.PlotResiduals, 'Counts');

            uialert(app.UIFigure, 'TRPL data loaded successfully!', 'Success');
        end

        % Button pushed function: BuildModelButton
        function BuildModelButtonPushed(app, event)
            % Ask user how many of each component
            prompt = { ...
                '# Exponential components:', ...
                '# Power-law components:', ...
                '# Second-order components:' ...
                };
            dlgTitle = 'Build Fit Model';
            dims = [1 40];
            def = {'1','0','0'};
            answer = inputdlg(prompt, dlgTitle, dims, def);
            if isempty(answer), return; end

            nExp = max(0, round(str2double(answer{1})));
            nPow = max(0, round(str2double(answer{2})));
            nSec = max(0, round(str2double(answer{3})));

            % Validate that at least one component is specified
            if nExp + nPow + nSec == 0
                uialert(app.UIFigure, 'At least one component must be specified.', 'Model Error');
                return;
            end

            % Store component counts
            app.NumExpComponents = nExp;
            app.NumPowerComponents = nPow;
            app.NumSecondComponents = nSec;

            % Generate model name
            modelParts = {};
            if nExp > 0, modelParts{end+1} = sprintf('%dexp', nExp); end
            if nPow > 0, modelParts{end+1} = sprintf('%dpower', nPow); end
            if nSec > 0, modelParts{end+1} = sprintf('%dsecond', nSec); end
            app.ModelName = strjoin(modelParts, '');

            % Build parameter table
            app.buildParameterTable();

            uialert(app.UIFigure, sprintf('Model "%s" built successfully!', app.ModelName), 'Build Success');
        end

        % Button down function: PlotData
        function PlotDataButtonDown(app, event)


        end

        % Button pushed function: ResetClearButton
        function ResetClearButtonPushed(app, event)
            % --- Clear all internal data & state ---
            app.RawTime                   = [];
            app.RawIRF                    = [];
            app.RawData                   = [];
            app.FitResults                = struct();
            app.FitType                   = [];
            app.FitOptions                = [];
            app.FitModel                  = '';
            app.FitRangeClicks            = [];
            app.BaselineRangeClicks       = [];
            app.NumExpComponents          = 0;
            app.NumPowerComponents        = 0;
            app.NumSecondComponents       = 0;
            app.ModelName                 = '';
            app.ParameterTable            = {};
            app.IRFTimeShift              = 0;
            app.FitRangeMarkers           = matlab.graphics.chart.primitive.Line.empty;
            app.BaselineRangeMarkers      = matlab.graphics.chart.primitive.Line.empty;
            app.FitRangePatch             = matlab.graphics.primitive.Patch.empty;
            app.BaselineRangePatch        = matlab.graphics.primitive.Patch.empty;

            % Remove any lingering patches, markers, draggable lines
            delete(findall(app.PlotData,'-regexp','Tag','window|baseline'));

            % --- Fully reset both axes ---
            cla(app.PlotData,  'reset');
            cla(app.PlotResiduals, 'reset');

            % Restore axes labels, title, and scale to first‐launch defaults
            title(app.PlotData, '');
            xlabel(app.PlotData, 'Time (ns)');
            ylabel(app.PlotData, 'Counts');
            app.PlotData.XScale = 'linear';
            app.PlotData.YScale = 'linear';

            title(app.PlotResiduals, '');
            xlabel(app.PlotResiduals, 'Time (ns)');
            ylabel(app.PlotResiduals, 'Residuals');
            app.PlotResiduals.XScale = 'linear';
            app.PlotResiduals.YScale = 'linear';

            % Re-enable click callback on the main axes
            app.PlotData.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);

            % --- Reset UI controls to their defaults ---
            app.ConvPadValue                              = 15;
            app.ConvPadnsEditField.Value                  = 15;
            app.WindowLBValue                             = 48;
            app.WindowLowerBoundnsEditField.Value         = 48;
            app.WindowUBValue                             = 80;
            app.WindowUpperBoundnsEditField.Value         = 80;
            app.WindowBaselineStartValue                  = 20;
            app.WindowBaselineStartindexEditField.Value   = 20;
            app.WindowBaselineEndValue                    = 40;
            app.WindowBaselineEndindexEditField.Value     = 40;

            app.SetWindowBoundsButton.Value               = false;
            app.SetWindowBoundsButton.Text                = 'Set Window Bounds';
            app.SetWindowBoundsButton.BackgroundColor     = [1 1 1];

            app.SetBaselineBoundsButton.Value             = false;
            app.SetBaselineBoundsButton.Text              = 'Set Baseline Bounds';
            app.SetBaselineBoundsButton.BackgroundColor   = [1 1 1];

            % Reset the parameter table structure
            app.FitParametersTable.Data       = {};
            app.FitParametersTable.ColumnName = { ...
                'Paramater'; ...
                'Initial Guess'; ...
                'Lower Bound'; ...
                'Upper Bound';  ...
                'Fit Result' ...
                };

            % Notify user
            uialert(app.UIFigure, 'All data and settings have been reset.', 'Reset Complete');


        end

        % Value changed function: SetWindowBoundsButton
        function SetWindowBoundsButtonValueChanged(app, event)
            if app.SetWindowBoundsButton.Value
                % Remove any old fit-lines
                delete(findobj(app.PlotData,'Tag','windowLineLow'));
                delete(findobj(app.PlotData,'Tag','windowLineHigh'));

                % Compute mid-segment of the current X-axis
                xl    = app.PlotData.XLim;
                width = diff(xl);
                newLB = xl(1) + 0.25*width;  % 25% in from left
                newUB = xl(1) + 0.75*width;  % 25% in from right

                % Update the numeric fields + stored values
                app.WindowLowerBoundnsEditField.Value = newLB;
                app.WindowUpperBoundnsEditField.Value = newUB;
                app.WindowLBValue = newLB;
                app.WindowUBValue = newUB;

                % Draw draggable lines at those positions
                yl = app.PlotData.YLim;
                lLow = drawline(app.PlotData, ...
                    'Position',[newLB yl(1); newLB yl(2)], ...
                    'InteractionsAllowed','translate', ...
                    'Color',[0.3,0.3,0.7], ...
                    'Tag','windowLineLow');
                lHigh = drawline(app.PlotData, ...
                    'Position',[newUB yl(1); newUB yl(2)], ...
                    'InteractionsAllowed','translate', ...
                    'Color',[0.3,0.3,0.7], ...
                    'Tag','windowLineHigh');

                % Live-update as the user drags
                addlistener(lLow,  'MovingROI', @(s,e) updateWindowLB(app,s,e));
                addlistener(lHigh, 'MovingROI', @(s,e) updateWindowUB(app,s,e));

                % Toggle appearance
                app.SetWindowBoundsButton.Text            = 'Adjust Fit Bounds';
                app.SetWindowBoundsButton.BackgroundColor  = [0.8 1 0.8];
            else
                % Turn off: remove lines & restore button style
                delete(findobj(app.PlotData,'Tag','windowLineLow'));
                delete(findobj(app.PlotData,'Tag','windowLineHigh'));
                app.SetWindowBoundsButton.Text           = 'Set Window Bounds';
                app.SetWindowBoundsButton.BackgroundColor = [1 1 1];
            end
        end

        % Value changed function: SetBaselineBoundsButton
        function SetBaselineBoundsButtonValueChanged(app, event)
            if app.SetBaselineBoundsButton.Value
                % --- Guard: require data loaded ---
                if isempty(app.RawTime) || isempty(app.RawData)
                    uialert(app.UIFigure, ...
                        'Please load IRF and decay data before setting baseline bounds.', ...
                        'Data Missing');
                    % turn the toggle back off
                    app.SetBaselineBoundsButton.Value           = false;
                    return
                end

                % Remove any old baseline lines
                delete(findobj(app.PlotData,'Tag','baselineLineLow'));
                delete(findobj(app.PlotData,'Tag','baselineLineHigh'));

                % Compute 25%/75% of the current X-axis limits
                xl    = app.PlotData.XLim;
                width = diff(xl);
                newStartX = xl(1) + 0.25*width;
                newEndX   = xl(1) + 0.75*width;

                % Convert those positions back to data indices
                [~, startIdx] = min(abs(app.RawTime - newStartX));
                [~, endIdx]   = min(abs(app.RawTime - newEndX));

                % Update numeric fields & stored values
                app.WindowBaselineStartindexEditField.Value = startIdx;
                app.WindowBaselineEndindexEditField.Value   = endIdx;
                app.WindowBaselineStartValue = startIdx;
                app.WindowBaselineEndValue   = endIdx;

                % Draw the draggable lines at those X positions
                yl = app.PlotData.YLim;
                lLow = drawline(app.PlotData, ...
                    'Position',[newStartX yl(1); newStartX yl(2)], ...
                    'InteractionsAllowed','translate', ...
                    'Color',[0.7,0.3,0.3], ...
                    'Tag','baselineLineLow');
                lHigh = drawline(app.PlotData, ...
                    'Position',[newEndX   yl(1); newEndX   yl(2)], ...
                    'InteractionsAllowed','translate', ...
                    'Color',[0.7,0.3,0.3], ...
                    'Tag','baselineLineHigh');

                % Live-update as the user drags
                addlistener(lLow,  'MovingROI', @(s,e) updateBaselineStart(app,s,e));
                addlistener(lHigh, 'MovingROI', @(s,e) updateBaselineEnd(app,s,e));

                % Toggle appearance
                app.SetBaselineBoundsButton.Text           = 'Adjust Baseline';
                app.SetBaselineBoundsButton.BackgroundColor = [1 0.8 0.8];

            else
                % Turn off: remove lines & restore button style
                delete(findobj(app.PlotData,'Tag','baselineLineLow'));
                delete(findobj(app.PlotData,'Tag','baselineLineHigh'));
                app.SetBaselineBoundsButton.Text           = 'Set Baseline Bounds';
                app.SetBaselineBoundsButton.BackgroundColor = [1 1 1];
            end

        end

        % Value changed function: ConvPadnsEditField
        function ConvPadnsEditFieldValueChanged2(app, event)
            value = app.ConvPadnsEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PlotResiduals = uiaxes(app.GridLayout);
            xlabel(app.PlotResiduals, 'Time (ns)')
            ylabel(app.PlotResiduals, 'Residuals')
            zlabel(app.PlotResiduals, 'Z')
            app.PlotData = uiaxes(app.GridLayout);
            xlabel(app.PlotData, 'Time (ns)')
            ylabel(app.PlotData, 'Counts')
            zlabel(app.PlotData, 'Z')
            app.PlotData.ButtonDownFcn = createCallbackFcn(app, @PlotDataButtonDown, true);
            app.LoadIRFDataButton = uibutton(app.GridLayout, 'push');
            app.LoadIRFDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIRFDataButtonPushed, true);
            app.LoadIRFDataButton.Text = 'Load IRF Data';
            app.ResetClearButton = uibutton(app.GridLayout, 'push');
            app.ResetClearButton.ButtonPushedFcn = createCallbackFcn(app, @ResetClearButtonPushed, true);
            app.ResetClearButton.Text = 'Reset/Clear ';
            app.LoadDecayDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDecayDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDecayDataButtonPushed, true);
            app.LoadDecayDataButton.Text = 'Load Decay Data';
            app.SetWindowBoundsButton = uibutton(app.GridLayout, 'state');
            app.SetWindowBoundsButton.ValueChangedFcn = createCallbackFcn(app, @SetWindowBoundsButtonValueChanged, true);
            app.SetWindowBoundsButton.Text = 'Set Window Bounds';
            app.SetBaselineBoundsButton = uibutton(app.GridLayout, 'state');
            app.SetBaselineBoundsButton.ValueChangedFcn = createCallbackFcn(app, @SetBaselineBoundsButtonValueChanged, true);
            app.SetBaselineBoundsButton.Text = 'Set Baseline Bounds';
            app.ExportResidualsasFigButton = uibutton(app.GridLayout, 'push');
            app.ExportResidualsasFigButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResidualsasFigButtonPushed, true);
            app.ExportResidualsasFigButton.Text = 'Export Residuals as Fig';
            app.ExportTRPLDecayasFigButton = uibutton(app.GridLayout, 'push');
            app.ExportTRPLDecayasFigButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTRPLDecayasFigButtonPushed, true);
            app.ExportTRPLDecayasFigButton.Text = 'Export TRPL Decay as Fig ';
            app.ExportDataasmatButton = uibutton(app.GridLayout, 'push');
            app.ExportDataasmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataasmatButtonPushed, true);
            app.ExportDataasmatButton.Text = 'Export Data as .mat';
            app.FitButton = uibutton(app.GridLayout, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit ';
            app.BuildModelButton = uibutton(app.GridLayout, 'push');
            app.BuildModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildModelButtonPushed, true);
            app.BuildModelButton.Text = 'Build Model';
            app.FitParametersTable = uitable(app.GridLayout);
            app.FitParametersTable.ColumnName = {'Parameter'; 'Initial Guess'; 'Lower Bound'; 'Upper Bound'; 'Fit Result'};
            app.FitParametersTable.RowName = {};
            app.FitParametersTable.ColumnEditable = [false true true true false];
            app.WindowLowerBoundnsEditFieldLabel = uilabel(app.GridLayout);
            app.WindowLowerBoundnsEditFieldLabel.Text = 'Window Lower Bound (ns)';
            app.WindowLowerBoundnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowLowerBoundnsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLowerBoundnsEditFieldValueChanged, true);
            app.WindowLowerBoundnsEditField.Value = 48;
            app.WindowUpperBoundnsEditFieldLabel = uilabel(app.GridLayout);
            app.WindowUpperBoundnsEditFieldLabel.Text = {'Window Upper Bound (ns)'; ''};
            app.WindowUpperBoundnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowUpperBoundnsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowUpperBoundnsEditFieldValueChanged, true);
            app.WindowUpperBoundnsEditField.Value = 80;
            app.WindowBaselineStartindexEditFieldLabel = uilabel(app.GridLayout);
            app.WindowBaselineStartindexEditFieldLabel.Text = 'Window Baseline Start (ns)';
            app.WindowBaselineStartindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowBaselineStartindexEditField.ValueChangedFcn = createCallbackFcn(app, @WindowBaselineStartindexEditFieldValueChanged, true);
            app.WindowBaselineStartindexEditField.Value = 20;
            app.WindowBaselineEndindexEditFieldLabel = uilabel(app.GridLayout);
            app.WindowBaselineEndindexEditFieldLabel.Text = 'Window Baseline End (ns)';
            app.WindowBaselineEndindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowBaselineEndindexEditField.ValueChangedFcn = createCallbackFcn(app, @WindowBaselineEndindexEditFieldValueChanged, true);
            app.WindowBaselineEndindexEditField.Value = 40;
            app.ConvPadnsEditFieldLabel = uilabel(app.GridLayout);
            app.ConvPadnsEditFieldLabel.Text = 'Conv Pad (ns)';
            app.ConvPadnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConvPadnsEditField.ValueChangedFcn = createCallbackFcn(app, @ConvPadnsEditFieldValueChanged2, true);
            app.ConvPadnsEditField.Value = 15;
            app.numMultiStart = uieditfield(app.GridLayout, 'numeric');
            app.numMultiStart.Value = 20;
            app.NumberoffittrialstartpointsLabel = uilabel(app.GridLayout);
            app.NumberoffittrialstartpointsLabel.Text = 'Number of fit trial start points ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRPL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end